classdef Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotButton                  matlab.ui.control.Button
        EquationEditField           matlab.ui.control.EditField
        EquationEditFieldLabel      matlab.ui.control.Label
        YCoordinatesEditField       matlab.ui.control.EditField
        YCoordinatesEditFieldLabel  matlab.ui.control.Label
        XCoordinatesEditField       matlab.ui.control.EditField
        XCoordinatesEditFieldLabel  matlab.ui.control.Label
        PlottingButtonGroup         matlab.ui.container.ButtonGroup
        CosineButton                matlab.ui.control.RadioButton
        SineButton                  matlab.ui.control.RadioButton
        EquationButton              matlab.ui.control.RadioButton
        XYAxisButton                matlab.ui.control.RadioButton
        YAxisButton                 matlab.ui.control.RadioButton
        XAxisButton                 matlab.ui.control.RadioButton
        Matrix2EditField            matlab.ui.control.EditField
        Matrix2EditFieldLabel       matlab.ui.control.Label
        Matrix1EditField            matlab.ui.control.EditField
        Matrix1EditFieldLabel       matlab.ui.control.Label
        MatrixResultLabel           matlab.ui.control.Label
        NumericCalculatorPanel      matlab.ui.container.Panel
        OperationListBox            matlab.ui.control.ListBox
        OperationListBoxLabel       matlab.ui.control.Label
        Number1EditField            matlab.ui.control.NumericEditField
        Number1EditFieldLabel       matlab.ui.control.Label
        Error_logLabel              matlab.ui.control.Label
        ResultButton                matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        ResultLabel                 matlab.ui.control.Label
        Label2                      matlab.ui.control.Label
        Number2EditField            matlab.ui.control.NumericEditField
        Number2EditFieldLabel       matlab.ui.control.Label
        MatrixCalculatorPanel       matlab.ui.container.Panel
        ResultLabel_2               matlab.ui.control.Label
        ResultButton_2              matlab.ui.control.Button
        OperationDropDown           matlab.ui.control.DropDown
        OperationDropDownLabel      matlab.ui.control.Label
        Error_logLabel_3            matlab.ui.control.Label
        Error_logLabel_2            matlab.ui.control.Label
        PlottingPanel               matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            global Num1;
            global Num2;
            global Operation;
            global Mat1;
            global Mat2;
            global MatOp;
            Num1 = 0;
            Num2 = 0;
            Mat1 = 0;
            Mat2 = 0;
            app.ResultLabel.Visible = 'off'
            app.ResultLabel_2.Visible = 'off'
            app.MatrixResultLabel.Visible = 'off'
            app.Label.Visible = 'off'
            app.Label2.Visible = 'off'
            app.Error_logLabel.Visible = 'off'
            app.Error_logLabel_2.Visible = 'off'
            app.Error_logLabel_3.Visible = 'off'
        end

        % Value changed function: OperationListBox
        function OperationListBoxValueChanged(app, event)
            global Operation;
            Operation = app.OperationListBox.Value;
            app.Label2.Text = Operation;
            app.Label2.Visible = 'on'
        end

        % Value changed function: Number1EditField
        function Number1EditFieldValueChanged(app, event)
            global Num1;
            Num1 = app.Number1EditField.Value;
            
        end

        % Value changed function: Number2EditField
        function Number2EditFieldValueChanged(app, event)
            global Num2;
            Num2 = app.Number2EditField.Value;
            
        end

        % Button pushed function: ResultButton
        function ResultButtonPushed(app, event)
            global Num1;
            global Num2;
            global Operation;
            result = 0;
            if Operation == '+'
                result = Num1 + Num2; 
                app.Error_logLabel.Visible = 'off';
            elseif Operation == '-'
                result = Num1 - Num2;
                app.Error_logLabel.Visible = 'off';
            elseif Operation == '/'
                if Num2 == 0
                    app.Error_logLabel.Text = 'Can not divide by zero';
                    app.Error_logLabel.Visible = 'on';
                else
                result = Num1 / Num2;
                end
            elseif Operation == '*'
                result = Num1 * Num2;
                app.Error_logLabel.Visible = 'off';
            end
            app.ResultLabel.Text = int2str( result );
            app.Label.Visible = 'on'
            app.ResultLabel.Visible = 'on'
            
        end

        % Value changed function: OperationDropDown
        function OperationDropDownValueChanged(app, event)
           global MatOp;
           MatOp = app.OperationDropDown.Value;
            
        end

        % Button pushed function: ResultButton_2
        function ResultButton_2Pushed(app, event)
            global Mat1;
            global Mat2;
            global MatOp;
            result = 0;
            if MatOp == '+'
                result = Mat1 + Mat2; 
                app.Error_logLabel_2.Visible = 'off';
                app.Error_logLabel_3.Visible = 'off';
            elseif MatOp == '-'
                result = Mat1 - Mat2;
                app.Error_logLabel_2.Visible = 'off';
                app.Error_logLabel_3.Visible = 'off';
                app.Error_logLabel_3.Visible = 'off';
            elseif MatOp == '/'
                if Mat2 == 0
                    app.Error_logLabel_2.Text = 'Can not divide by zero';
                    app.Error_logLabel_2.Visible = 'on';                   
                    app.Error_logLabel_3.Visible = 'off';
                else
                result = Mat1 ./ Mat2;
                end
            elseif MatOp == '*'
                [rows1, cols1] = size(Mat1);
                [rows2, cols2] = size(Mat2);
                if cols1 == rows2
                    result = Mat1 * Mat2;
                    app.Error_logLabel_2.Visible = 'off';
                else
                    app.Error_logLabel_3.Text = 'Matrices sizes do not match';
                    app.Error_logLabel_3.Visible = 'on'; 
                    app.Error_logLabel_2.Visible = 'off';
                end
            end
            app.MatrixResultLabel.Text = mat2str( result );
            app.MatrixResultLabel.Visible = 'on'
            app.ResultLabel_2.Visible = 'on'
        end

        % Value changed function: Matrix1EditField
        function Matrix1EditFieldValueChanged(app, event)
            global Mat1;
            strMatrix = app.Matrix1EditField.Value;
            Mat1 = eval(strMatrix);
   
        end

        % Value changed function: Matrix2EditField
        function Matrix2EditFieldValueChanged(app, event)
            global Mat2;
            strMatrix = app.Matrix1EditField.Value;
            Mat2 = eval(strMatrix);
   
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if(app.CosineButton.Value == true)
                theta = 0:0.1:360;
                plot(app.UIAxes,cosd(theta));
                title(app.UIAxes,'Cosine Wave');
                
            elseif(app.SineButton.Value == true)
                theta = 0:0.1:360;
                plot(app.UIAxes,sind(theta));
                title(app.UIAxes,'Sine Wave');
                
            elseif(app.EquationButton.Value == true)
                equationString = app.EquationEditField.Value;
                equationString = strrep(equationString, 'x', 'x.');
                x = linspace(-30, 30, 150);
                y = eval(equationString);
                plot(app.UIAxes, x, y);
                title(app.UIAxes,'Equation Wave');
                
            elseif(app.XAxisButton.Value == true)
                x = str2num(app.XCoordinatesEditField.Value);
                y = str2num(app.YCoordinatesEditField.Value);
                y = zeros(size(x));
                plot(app.UIAxes, x, y);
                title(app.UIAxes,'X-Axis');
                
            elseif(app.YAxisButton.Value == true)
                x = str2num(app.XCoordinatesEditField.Value);
                y = str2num(app.YCoordinatesEditField.Value);
                x = zeros(size(y));
                plot(app.UIAxes, x, y);
                title(app.UIAxes,'Y-Axis');
                
            elseif(app.XYAxisButton.Value == true) 
               x = str2num(app.XCoordinatesEditField.Value);
               y = str2num(app.YCoordinatesEditField.Value);
               plot(app.UIAxes, x, y);
               title(app.UIAxes,'X,Y-Axis');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlottingPanel = uipanel(app.UIFigure);
            app.PlottingPanel.Title = 'Plotting';
            app.MatrixCalculatorPanel = uipanel(app.UIFigure);
            app.MatrixCalculatorPanel.Title = 'Matrix Calculator';
            app.Error_logLabel_2 = uilabel(app.MatrixCalculatorPanel);
            app.Error_logLabel_2.Text = 'Error_log';
            app.Error_logLabel_3 = uilabel(app.MatrixCalculatorPanel);
            app.Error_logLabel_3.Text = 'Error_log';
            app.OperationDropDownLabel = uilabel(app.MatrixCalculatorPanel);
            app.OperationDropDownLabel.Text = 'Operation';
            app.OperationDropDown = uidropdown(app.MatrixCalculatorPanel);
            app.OperationDropDown.Items = {'+', '-', '/', '*'};
            app.OperationDropDown.ValueChangedFcn = createCallbackFcn(app, @OperationDropDownValueChanged, true);
            app.OperationDropDown.Value = '+';
            app.ResultButton_2 = uibutton(app.MatrixCalculatorPanel, 'push');
            app.ResultButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResultButton_2Pushed, true);
            app.ResultButton_2.Text = 'Result';
            app.ResultLabel_2 = uilabel(app.MatrixCalculatorPanel);
            app.ResultLabel_2.Text = 'Result';
            app.NumericCalculatorPanel = uipanel(app.UIFigure);
            app.NumericCalculatorPanel.Title = 'Numeric Calculator';
            app.Number2EditFieldLabel = uilabel(app.NumericCalculatorPanel);
            app.Number2EditFieldLabel.Text = 'Number 2';
            app.Number2EditField = uieditfield(app.NumericCalculatorPanel, 'numeric');
            app.Number2EditField.ValueChangedFcn = createCallbackFcn(app, @Number2EditFieldValueChanged, true);
            app.Label2 = uilabel(app.NumericCalculatorPanel);
            app.Label2.Text = 'Label2';
            app.ResultLabel = uilabel(app.NumericCalculatorPanel);
            app.ResultLabel.Text = 'Result';
            app.Label = uilabel(app.NumericCalculatorPanel);
            app.Label.Text = '=';
            app.ResultButton = uibutton(app.NumericCalculatorPanel, 'push');
            app.ResultButton.ButtonPushedFcn = createCallbackFcn(app, @ResultButtonPushed, true);
            app.ResultButton.Text = 'Result';
            app.Error_logLabel = uilabel(app.NumericCalculatorPanel);
            app.Error_logLabel.Text = 'Error_log';
            app.Number1EditFieldLabel = uilabel(app.NumericCalculatorPanel);
            app.Number1EditFieldLabel.Text = {'Number 1'; ''};
            app.Number1EditField = uieditfield(app.NumericCalculatorPanel, 'numeric');
            app.Number1EditField.ValueChangedFcn = createCallbackFcn(app, @Number1EditFieldValueChanged, true);
            app.OperationListBoxLabel = uilabel(app.NumericCalculatorPanel);
            app.OperationListBoxLabel.Text = 'Operation';
            app.OperationListBox = uilistbox(app.NumericCalculatorPanel);
            app.OperationListBox.Items = {'+', '-', '/', '*'};
            app.OperationListBox.ValueChangedFcn = createCallbackFcn(app, @OperationListBoxValueChanged, true);
            app.OperationListBox.Value = '+';
            app.MatrixResultLabel = uilabel(app.UIFigure);
            app.MatrixResultLabel.Text = 'MatrixResult';
            app.Matrix1EditFieldLabel = uilabel(app.UIFigure);
            app.Matrix1EditFieldLabel.Text = 'Matrix 1';
            app.Matrix1EditField = uieditfield(app.UIFigure, 'text');
            app.Matrix1EditField.ValueChangedFcn = createCallbackFcn(app, @Matrix1EditFieldValueChanged, true);
            app.Matrix2EditFieldLabel = uilabel(app.UIFigure);
            app.Matrix2EditFieldLabel.Text = 'Matrix 2';
            app.Matrix2EditField = uieditfield(app.UIFigure, 'text');
            app.Matrix2EditField.ValueChangedFcn = createCallbackFcn(app, @Matrix2EditFieldValueChanged, true);
            app.PlottingButtonGroup = uibuttongroup(app.UIFigure);
            app.PlottingButtonGroup.Title = 'Plotting';
            app.XAxisButton = uiradiobutton(app.PlottingButtonGroup);
            app.XAxisButton.Text = 'X-Axis';
            app.XAxisButton.Value = true;
            app.YAxisButton = uiradiobutton(app.PlottingButtonGroup);
            app.YAxisButton.Text = 'Y-Axis';
            app.XYAxisButton = uiradiobutton(app.PlottingButtonGroup);
            app.XYAxisButton.Text = 'X,Y-Axis';
            app.EquationButton = uiradiobutton(app.PlottingButtonGroup);
            app.EquationButton.Text = 'Equation';
            app.SineButton = uiradiobutton(app.PlottingButtonGroup);
            app.SineButton.Text = 'Sine';
            app.CosineButton = uiradiobutton(app.PlottingButtonGroup);
            app.CosineButton.Text = 'Cosine';
            app.XCoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.XCoordinatesEditFieldLabel.Text = 'X Coordinates';
            app.XCoordinatesEditField = uieditfield(app.UIFigure, 'text');
            app.YCoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.YCoordinatesEditFieldLabel.Text = 'Y Coordinates';
            app.YCoordinatesEditField = uieditfield(app.UIFigure, 'text');
            app.EquationEditFieldLabel = uilabel(app.UIFigure);
            app.EquationEditFieldLabel.Text = 'Equation';
            app.EquationEditField = uieditfield(app.UIFigure, 'text');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end