classdef Traffic_Light_Modes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ManualButton_2       matlab.ui.control.Button
        AutoButton_2         matlab.ui.control.Button
        greenLamp            matlab.ui.control.Lamp
        yellowLamp           matlab.ui.control.Lamp
        redLamp              matlab.ui.control.Lamp
        AutomaticTimerPanel  matlab.ui.container.Panel
        TimeLabel            matlab.ui.control.Label
        ManualModePanel      matlab.ui.container.Panel
        RedButton            matlab.ui.control.Button
        YellowButton         matlab.ui.control.Button
        GreenButton          matlab.ui.control.Button
        Panel                matlab.ui.container.Panel
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            global Mode;
            global ArdObj;
            ArdObj = 0;
            %ArdObj = arduino('COM',"UNO");
            %configurePin(ArdObj,'D10','DigitalOutput');
            %configurePin(ArdObj,'D11','DigitalOutput');
            %configurePin(ArdObj,'D12','DigitalOutput');
            Mode = 0; 
            app.AutomaticTimerPanel.Enable = 'on';
            app.ManualModePanel.Enable = 'off';
        end

        % Button pushed function: RedButton
        function RedButtonPushed(app, event)
            global ArdObj;
            if(ArdObj == 0)
               app.redLamp.Color = 'red'; 
               app.yellowLamp.Color = 'w';
               app.greenLamp.Color = 'w';
            else
                writeDigitalPin(ArdObj,'D10',1);
                writeDigitalPin(ArdObj,'D11',0);
                writeDigitalPin(ArdObj,'D12',0);
            end
            
        end

        % Button pushed function: YellowButton
        function YellowButtonPushed(app, event)
           global ArdObj;
            if(ArdObj == 0)
               app.redLamp.Color = 'w'; 
               app.yellowLamp.Color = 'y';
               app.greenLamp.Color = 'w';
            else
                writeDigitalPin(ArdObj,'D10',0);
                writeDigitalPin(ArdObj,'D11',1);
                writeDigitalPin(ArdObj,'D12',0);
            end
            
        end

        % Button pushed function: GreenButton
        function GreenButtonPushed(app, event)
               global ArdObj;
            if(ArdObj == 0)
               app.redLamp.Color = 'w'; 
               app.yellowLamp.Color = 'w';
               app.greenLamp.Color = 'g';
            else
                writeDigitalPin(ArdObj,'D10',0);
                writeDigitalPin(ArdObj,'D11',0);
                writeDigitalPin(ArdObj,'D12',1);
            end
            
        end

        % Button pushed function: ManualButton_2
        function ManualButton_2Pushed(app, event)
            global Mode;
            Mode = 0;
            app.AutomaticTimerPanel.Enable = 'off';
            app.ManualModePanel.Enable = 'on';
            
        end

        % Button pushed function: AutoButton_2
        function AutoButton_2Pushed(app, event)
            global Mode;
            global ArdObj;
            Mode = 1;
            app.AutomaticTimerPanel.Enable = 'on';
            app.ManualModePanel.Enable = 'of';
            
            lampCount = 3;
          while (Mode == 1)
                TimeCount = 30;
                
           while (TimeCount > 0 && Mode ==1)               
            if(ArdObj == 0)
               switch lampCount
               case 3
               writeDigitalPin(ArdObj,'D10',1);
               writeDigitalPin(ArdObj,'D11',0);
               writeDigitalPin(ArdObj,'D12',0);
               case 2
               writeDigitalPin(ArdObj,'D10',0);
               writeDigitalPin(ArdObj,'D11',1);
               writeDigitalPin(ArdObj,'D12',0);
               case 1
               writeDigitalPin(ArdObj,'D10',0);
               writeDigitalPin(ArdObj,'D11',0);
               writeDigitalPin(ArdObj,'D12',1);
               end
            else
               switch lampCount
               case 3
               app.redLamp.Color = 'r'; 
               app.yellowLamp.Color = 'w';
               app.greenLamp.Color = 'w';
               case 2
               app.redLamp.Color = 'w'; 
               app.yellowLamp.Color = 'y';
               app.greenLamp.Color = 'w';
               case 1
               app.redLamp.Color = 'w'; 
               app.yellowLamp.Color = 'w';
               app.greenLamp.Color = 'g';
               end
            end
                app.TimeLabel.Text = num2str(TimeCount);
                pause(1);
                TimeCount = TimeCount -1; 
                 
           end
                lampCount = lampCount -1;
                if lampCount == 0
                   lampCount = 3;
                end              
         
          end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.ManualModePanel = uipanel(app.UIFigure);
            app.ManualModePanel.Title = 'Manual Mode';
            app.GreenButton = uibutton(app.ManualModePanel, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed, true);
            app.GreenButton.Text = 'Green';
            app.YellowButton = uibutton(app.ManualModePanel, 'push');
            app.YellowButton.ButtonPushedFcn = createCallbackFcn(app, @YellowButtonPushed, true);
            app.YellowButton.Text = 'Yellow';
            app.RedButton = uibutton(app.ManualModePanel, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @RedButtonPushed, true);
            app.RedButton.Text = 'Red';
            app.AutomaticTimerPanel = uipanel(app.UIFigure);
            app.AutomaticTimerPanel.Title = 'Automatic Timer';
            app.TimeLabel = uilabel(app.AutomaticTimerPanel);
            app.TimeLabel.Text = 'Time';
            app.redLamp = uilamp(app.UIFigure);
            app.yellowLamp = uilamp(app.UIFigure);
            app.greenLamp = uilamp(app.UIFigure);
            app.AutoButton_2 = uibutton(app.UIFigure, 'push');
            app.AutoButton_2.ButtonPushedFcn = createCallbackFcn(app, @AutoButton_2Pushed, true);
            app.AutoButton_2.Text = 'Auto';
            app.ManualButton_2 = uibutton(app.UIFigure, 'push');
            app.ManualButton_2.ButtonPushedFcn = createCallbackFcn(app, @ManualButton_2Pushed, true);
            app.ManualButton_2.Text = 'Manual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Traffic_Light_Modes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end